classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Toolbar                        matlab.ui.container.Toolbar
        PushTool                       matlab.ui.container.toolbar.PushTool
        TabGroup                       matlab.ui.container.TabGroup
        NetworkTab                     matlab.ui.container.Tab
        ApplyButton                    matlab.ui.control.Button
        TabGroup2                      matlab.ui.container.TabGroup
        ClassificationTab              matlab.ui.container.Tab
        SelectmodelButtonGroup         matlab.ui.container.ButtonGroup
        NumberofclassesEditField_2     matlab.ui.control.NumericEditField
        NumberofclassesEditField_2Label  matlab.ui.control.Label
        ImagesizeEditField_x           matlab.ui.control.NumericEditField
        ImagesizeEditField_2Label      matlab.ui.control.Label
        ImagesizeEditFieldLabel_7      matlab.ui.control.Label
        ImagesizeEditFieldLabel_6      matlab.ui.control.Label
        ImagesizeEditFieldLabel_5      matlab.ui.control.Label
        ImagesizeEditField_z           matlab.ui.control.NumericEditField
        ImagesizeEditField_y           matlab.ui.control.NumericEditField
        IncludeweightsCheckBox         matlab.ui.control.CheckBox
        ResNet50Button                 matlab.ui.control.ToggleButton
        MobileNetv2Button              matlab.ui.control.ToggleButton
        Incepctionv3Button             matlab.ui.control.ToggleButton
        AlexNetButton                  matlab.ui.control.ToggleButton
        BuildfromscratchButton         matlab.ui.control.ToggleButton
        SegmentationTab                matlab.ui.container.Tab
        SelectmodelButtonGroup_2       matlab.ui.container.ButtonGroup
        ImagesizeEditFieldLabel_8      matlab.ui.control.Label
        ImagesizeEditFieldLabel_4      matlab.ui.control.Label
        ImagesizeEditFieldLabel_3      matlab.ui.control.Label
        ImagesizeEditFieldLabel_2      matlab.ui.control.Label
        ImagesizeEditField_z_s         matlab.ui.control.NumericEditField
        ImagesizeEditField_y_s         matlab.ui.control.NumericEditField
        ImagesizeEditField_x_s         matlab.ui.control.NumericEditField
        ImagesizeEditFieldLabel        matlab.ui.control.Label
        BackboneDropDown               matlab.ui.control.DropDown
        BackboneDropDownLabel          matlab.ui.control.Label
        NumberofclassesEditField       matlab.ui.control.NumericEditField
        NumberofclassesEditFieldLabel  matlab.ui.control.Label
        IncludeweightsCheckBox_2       matlab.ui.control.CheckBox
        UNetButton                     matlab.ui.control.ToggleButton
        DeepLabv3Button                matlab.ui.control.ToggleButton
        BuildfromscratchButton_2       matlab.ui.control.ToggleButton
        DetectionTab                   matlab.ui.container.Tab
        SelectmodelButtonGroup_3       matlab.ui.container.ButtonGroup
        IncludeweightsCheckBox_3       matlab.ui.control.CheckBox
        YOLOv4Button                   matlab.ui.control.ToggleButton
        BuildfromscratchButton_3       matlab.ui.control.ToggleButton
        DataTab                        matlab.ui.container.Tab
        Image                          matlab.ui.control.Image
        ClassnamesTextArea             matlab.ui.control.TextArea
        ClassnamesTextAreaLabel        matlab.ui.control.Label
        ApplyButton_2                  matlab.ui.control.Button
        TrainValidationTestdatarandompartitioningLabel  matlab.ui.control.Label
        ImporttableButton              matlab.ui.control.Button
        LabelssourcetableCheckBox      matlab.ui.control.CheckBox
        LabelssourcefoldernamesCheckBox  matlab.ui.control.CheckBox
        DatasetsstatisticsLabel        matlab.ui.control.Label
        TabGroup3                      matlab.ui.container.TabGroup
        TrainTab_2                     matlab.ui.container.Tab
        TransparencySlider             matlab.ui.control.Slider
        TransparencySliderLabel        matlab.ui.control.Label
        ClassLabel                     matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        Slider                         matlab.ui.control.Slider
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
        ValidationTab                  matlab.ui.container.Tab
        TransparencySlider_2           matlab.ui.control.Slider
        TransparencySlider_2Label      matlab.ui.control.Label
        ClassLabel_2                   matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        Slider_2                       matlab.ui.control.Slider
        UIAxes2_2                      matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
        TestTab_2                      matlab.ui.container.Tab
        TransparencySlider_3           matlab.ui.control.Slider
        TransparencySlider_3Label      matlab.ui.control.Label
        ClassLabel_3                   matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        Slider_3                       matlab.ui.control.Slider
        UIAxes2_3                      matlab.ui.control.UIAxes
        UIAxes_3                       matlab.ui.control.UIAxes
        TestdatasetEditField           matlab.ui.control.NumericEditField
        TestdatasetLabel               matlab.ui.control.Label
        IncludevalidationCheckBox      matlab.ui.control.CheckBox
        ValidationdatasetEditField     matlab.ui.control.NumericEditField
        ValidationdatasetEditFieldLabel  matlab.ui.control.Label
        TraindatasetEditField          matlab.ui.control.NumericEditField
        TraindatasetEditFieldLabel     matlab.ui.control.Label
        LoaddataButton                 matlab.ui.control.Button
        DataaugmentationTab            matlab.ui.container.Tab
        PanelSegmentation              matlab.ui.container.Panel
        UIAxes4_15                     matlab.ui.control.UIAxes
        UIAxes4_14                     matlab.ui.control.UIAxes
        UIAxes4_13                     matlab.ui.control.UIAxes
        UIAxes4_12                     matlab.ui.control.UIAxes
        UIAxes4_11                     matlab.ui.control.UIAxes
        UIAxes4_10                     matlab.ui.control.UIAxes
        PreviewaugmentationresultsLabel  matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
        PanelClassification            matlab.ui.container.Panel
        UIAxes4_3                      matlab.ui.control.UIAxes
        UIAxes4_4                      matlab.ui.control.UIAxes
        UIAxes4_5                      matlab.ui.control.UIAxes
        UIAxes4_6                      matlab.ui.control.UIAxes
        UIAxes4_7                      matlab.ui.control.UIAxes
        UIAxes4_2                      matlab.ui.control.UIAxes
        UIAxes4                        matlab.ui.control.UIAxes
        TrainTab                       matlab.ui.container.Tab
        TimeelapsedLabel               matlab.ui.control.Label
        TrainingwaitingtostartLabel    matlab.ui.control.Label
        HyperparametersPanel           matlab.ui.container.Panel
        ModeltotrainDropDown           matlab.ui.control.DropDown
        ModeltotrainDropDownLabel      matlab.ui.control.Label
        LossfunctionDropDown           matlab.ui.control.DropDown
        LossfunctionDropDownLabel      matlab.ui.control.Label
        TrainButton                    matlab.ui.control.Button
        UITable2                       matlab.ui.control.Table
        OptimizerDropDown              matlab.ui.control.DropDown
        OptimizerDropDownLabel         matlab.ui.control.Label
        UIAxesLoss                     matlab.ui.control.UIAxes
        UIAxesAccuracy                 matlab.ui.control.UIAxes
        TestTab                        matlab.ui.container.Tab
        Slider_4                       matlab.ui.control.Slider
        GradCAMButton                  matlab.ui.control.Button
        PredictedclassLabel            matlab.ui.control.Label
        GroundtruthclassLabel          matlab.ui.control.Label
        SelectsavedmodelDropDown       matlab.ui.control.DropDown
        SelectsavedmodelDropDownLabel  matlab.ui.control.Label
        TestmetricsPanel               matlab.ui.container.Panel
        AreaunderROCcurveLabel         matlab.ui.control.Label
        F1scoreLabel                   matlab.ui.control.Label
        RecallLabel                    matlab.ui.control.Label
        PrecisionLabel                 matlab.ui.control.Label
        AccuracyLabel                  matlab.ui.control.Label
        UIAxes4_9                      matlab.ui.control.UIAxes
        UIAxes4_8                      matlab.ui.control.UIAxes
        UIAxesConfMat                  matlab.ui.control.UIAxes
    end


    methods (Access = public)

        function stop = updatePlot(app, info)
            stop = false; % Allow training to continue
            global options
            % Create a persistent variable to store the loss values across iterations
            persistent lossHistory iterationHistory vallossHistory;

            % Check if info contains training data (i.e., loss)
            if ~isempty(info.TrainingLoss)
                iteration = info.Iteration;

                % Initialize the history variables if they don't exist
                if isempty(lossHistory)
                    lossHistory = info.TrainingLoss;  % Store the current loss in history
                    iterationHistory = iteration;     % Store the current iteration number
                    if app.IncludevalidationCheckBox.Value == 1
                        vallossHistory = info.ValidationLoss;  % Store the current loss in history
                    end
                else
                    % Append the current loss and iteration to the history
                    lossHistory = [lossHistory, info.TrainingLoss];
                    iterationHistory = [iterationHistory, iteration];
                    if app.IncludevalidationCheckBox.Value == 1
                        vallossHistory = [vallossHistory, info.ValidationLoss];  % Store the current loss in history
                    end
                end

                % Debugging: Display previous loss values
                disp('Previous Loss Values:');
                disp(lossHistory);

                % Update Loss Plot using animatedline
                persistent lossLine valLossLine;
                if isempty(lossLine)
                    lossLine = animatedline('Parent', app.UIAxesLoss, 'LineWidth', 1, 'Color', [0 0.4470 0.7410]);
                    xlabel(app.UIAxesLoss, 'Iteration');
                    ylabel(app.UIAxesLoss, 'Loss');
                    if app.IncludevalidationCheckBox.Value == 1
                        valLossLine = animatedline('Parent', app.UIAxesLoss, 'LineWidth', 1, 'Color', [0.9290 0.6940 0.1250]);
                    end
                end
                addpoints(lossLine, iteration, info.TrainingLoss(end));  % Add new point for loss
                if app.IncludevalidationCheckBox.Value == 1
                    if ~isempty(info.ValidationLoss)
                        addpoints(valLossLine, iteration, info.ValidationLoss(end));  % Add new point for loss
                    end
                end
                xlim(app.UIAxesLoss, [0 iteration]);  % Update the X axis limits

                if app.IncludevalidationCheckBox.Value == 1
                    ylim(app.UIAxesLoss, [0 max([lossHistory, vallossHistory])]);  % Adjust Y axis dynamically based on loss history
                else
                    ylim(app.UIAxesLoss, [0 max(lossHistory)]);  % Adjust Y axis dynamically based on loss history
                end

                if app.IncludevalidationCheckBox.Value == 1

                    legend(app.UIAxesLoss,["Training","Validation"])
                else
                    legend(app.UIAxesLoss,["Training"])

                end

                % You can also plot or access previous loss values here
                % For example, access the previous loss (before the current iteration)
                if iteration > 1
                    previousLoss = lossHistory(end-1);  % Get the previous loss
                    disp(['Previous Loss at Iteration ', num2str(iteration - 1), ': ', num2str(previousLoss)]);
                end

                % Update Accuracy Plot (if available)
                persistent accLine valaccLine;
                if ~isempty(info.TrainingAccuracy)
                    if isempty(accLine)
                        accLine = animatedline('Parent', app.UIAxesAccuracy, 'LineWidth', 1, 'Color', [0 0.4470 0.7410]);
                        xlabel(app.UIAxesAccuracy, 'Iteration');
                        ylabel(app.UIAxesAccuracy, 'Training Accuracy');
                        if app.IncludevalidationCheckBox.Value == 1
                            valaccLine = animatedline('Parent', app.UIAxesAccuracy, 'LineWidth', 1, 'Color', [0.9290 0.6940 0.1250]);
                        end
                    end
                    if app.IncludevalidationCheckBox.Value == 1
                        if ~isempty(info.ValidationAccuracy)
                            addpoints(valaccLine, iteration, info.ValidationAccuracy(end));  % Add a new point for accuracy
                        end
                    end
                    addpoints(accLine, iteration, info.TrainingAccuracy(end));  % Add a new point for accuracy
                    xlim(app.UIAxesAccuracy, [0 iteration]);  % Update the X axis limits
                    ylim(app.UIAxesAccuracy, [0 100]);  % Accuracy is typically between 0 and 1
                    if app.IncludevalidationCheckBox.Value == 1

                        legend(app.UIAxesAccuracy,["Training","Validation"])
                    else
                        legend(app.UIAxesAccuracy,["Training"])

                    end


                    if isempty(info.Epoch)
                        app.TrainingwaitingtostartLabel.Text = ['Training: in progress, please wait... Epoch: 0 of ' num2str(options.MaxEpochs)];
                    else
                        app.TrainingwaitingtostartLabel.Text = ['Training: in progress, please wait... Epoch: ' num2str(info.Epoch) ' of ' num2str(options.MaxEpochs)];
                    end
                    if isempty(info.TimeElapsed)
                        app.TimeelapsedLabel.Text = ['Time elapsed: ---- '];
                    else
                        app.TimeelapsedLabel.Text = ['Time elapsed: ' string(info.TimeElapsed)];
                    end
                end

                % Ensure the UI updates smoothly
                drawnow;
            end
        end


        function askForFilenameAndSave(app, trainedNet)
            % Create a new UI figure (popup)
            fig = uifigure('Name', 'Save trained network', 'Position', [500 500 300 150]);

            % Add a text label
            lbl = uilabel(fig, 'Text', 'Enter filename:', 'Position', [20 100 100 20]);

            % Add an input field for the filename
            filenameField = uieditfield(fig, 'text', 'Position', [120 100 150 22]);

            % Add a save button
            btn = uibutton(fig, 'push', 'Text', 'Save', ...
                'Position', [100 50 100 30], ...
                'ButtonPushedFcn', @(btn,event) saveNetwork(app, trainedNet, filenameField.Value, fig));
        end

        function saveNetwork(app, trainedNet, filename, fig)
            if isempty(filename)
                uialert(fig, 'Filename cannot be empty!', 'Error', 'Icon', 'error');
                return;
            end

            % Ensure filename ends with .mat
            if ~endsWith(filename, '.mat')
                filename = strcat(filename, '.mat');
            end

            % Save the trained network
            save(['./models/' filename], 'trainedNet');

            % Close the popup window
            close(fig);

            % Show a confirmation message
            uialert(app.UIFigure, ['Network saved as ', filename], 'Success', 'Icon', 'success');
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function PretrainedButtonGroupSelectionChanged(app, event)
            global model2use
            selectedButton = app.PretrainedButtonGroup.SelectedObject;
            if contains(selectedButton.Text,'AlexNet')
                model2use = alexnet();
            elseif contains(selectedButton.Text,'Inception')
                model2use = inceptionv3();
            elseif contains(selectedButton.Text,'MobileNet')
                model2use = mobilenetv2();
            end
        end

        % Callback function
        function PretrainedButtonGroupButtonDown(app, event)
            global model2use
            selectedButton = app.PretrainedButtonGroup.SelectedObject;
            if contains(selectedButton.Text,'AlexNet')
                model2use = alexnet();
            elseif contains(selectedButton.Text,'Inception')
                model2use = inceptionv3();
            elseif contains(selectedButton.Text,'MobileNet')
                model2use = mobilenetv2();
            end
        end

        % Callback function
        function CustomButtonGroupSelectionChanged(app, event)
            global model2use
            selectedButton = app.CustomButtonGroup.SelectedObject;
            if contains(selectedButton.Text,'Build from scratch')
                model2use = [];
            elseif contains(selectedButton.Text,'Load from workspace')
                model2use = [];
            end
        end

        % Callback function
        function CustomButtonGroupButtonDown(app, event)
            global model2use
            selectedButton = app.CustomButtonGroup.SelectedObject;
            if contains(selectedButton.Text,'Build from scratch')
                flag_build_from_scratch
                layers = imageInputLayer([28 28 1]); % Minimal input layer
                net = layerGraph(layers);
                deepNetworkDesigner(net);
            elseif contains(selectedButton.Text,'Load from workspace')
                model2use = [];
            end
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            global model2use flag
            selectedTab = app.TabGroup2.SelectedTab;
            flag = selectedTab.Title;

            if contains(flag, 'Classification')
                selectedButton = app.SelectmodelButtonGroup.SelectedObject;
                app.LabelssourcefoldernamesCheckBox.Enable = 'on';
                app.LabelssourcetableCheckBox.Enable = 'on';
                app.LoaddataButton.Tooltip = 'Indicate path to data directory.';
            elseif contains(flag, 'Segmentation')
                selectedButton = app.SelectmodelButtonGroup_2.SelectedObject;
                app.LabelssourcefoldernamesCheckBox.Enable = 'off';
                app.LabelssourcetableCheckBox.Enable = 'off';
                app.LoaddataButton.Tooltip = 'Indicate path to directory with folders named: "images" and "masks".';
            elseif contains(flag, 'Detection')
                selectedButton = app.SelectmodelButtonGroup_3.SelectedObject;
                app.LabelssourcefoldernamesCheckBox.Enable = 'off';
                app.LabelssourcetableCheckBox.Enable = 'off';
                app.LoaddataButton.Tooltip = '';
            end

            if contains(selectedButton.Text,'Build from scratch')
                layers = imageInputLayer([round(app.ImagesizeEditField_x.Value) round(app.ImagesizeEditField_y.Value) round(app.ImagesizeEditField_z.Value)]); % Minimal input layer
                model2use = layerGraph(layers);
                deepNetworkDesigner(model2use);
            elseif contains(selectedButton.Text,'AlexNet')
                if app.IncludeweightsCheckBox.Value == 1
                    model2use = imagePretrainedNetwork("alexnet",NumClasses=round(app.NumberofclassesEditField_2.Value), Weights="pretrained");
                else
                    model2use = imagePretrainedNetwork("alexnet",NumClasses=round(app.NumberofclassesEditField_2.Value), Weights="none");
                end
                deepNetworkDesigner(model2use);
            elseif contains(selectedButton.Text,'Inceptionv3')
                if app.IncludeweightsCheckBox.Value == 1
                    model2use = imagePretrainedNetwork("inceptionv3",NumClasses=round(app.NumberofclassesEditField_2.Value), Weights="pretrained");
                else
                    model2use = imagePretrainedNetwork("inceptionv3",NumClasses=round(app.NumberofclassesEditField_2.Value), Weights="none");
                end
                deepNetworkDesigner(model2use);
            elseif contains(selectedButton.Text,'MobileNetv2')
                if app.IncludeweightsCheckBox.Value == 1
                    model2use = imagePretrainedNetwork("mobilenetv2",NumClasses=round(app.NumberofclassesEditField_2.Value), Weights="pretrained");
                else
                    model2use = imagePretrainedNetwork("mobilenetv2",NumClasses=round(app.NumberofclassesEditField_2.Value), Weights="none");
                end
                deepNetworkDesigner(model2use);
            elseif contains(selectedButton.Text,'ResNet-50')
                if app.IncludeweightsCheckBox.Value == 1
                    model2use = imagePretrainedNetwork("resnet50",NumClasses=round(app.NumberofclassesEditField_2.Value), Weights="pretrained");
                else
                    model2use = imagePretrainedNetwork("resnet50",NumClasses=round(app.NumberofclassesEditField_2.Value), Weights="none");
                end
                deepNetworkDesigner(model2use);
            elseif contains(selectedButton.Text,'DeepLabv3+')
                if contains(app.BackboneDropDown.Value, 'ResNet50')
                    bckbone = "resnet50";
                end
                model2use = deeplabv3plus([round(app.ImagesizeEditField_x_s.Value) round(app.ImagesizeEditField_y_s.Value) round(app.ImagesizeEditField_z_s.Value)], round(app.NumberofclassesEditField.Value), bckbone);
                deepNetworkDesigner(model2use);
            elseif contains(selectedButton.Text,'UNet')
                model2use = unet([round(app.ImagesizeEditField_x_s.Value) round(app.ImagesizeEditField_y_s.Value) round(app.ImagesizeEditField_z_s.Value)], round(app.NumberofclassesEditField.Value));
                deepNetworkDesigner(model2use);
            elseif contains(selectedButton.Text,'YOLOv4')

            end
            if isempty(model2use)
                msgbox('Model is empty. Please choose an architecture.')
            else
                msgbox('Please wait.')
                deepNetworkDesigner(model2use);
            end
        end

        % Selection changed function: SelectmodelButtonGroup
        function SelectmodelButtonGroupSelectionChanged(app, event)
            global model2use
            selectedButton = app.SelectmodelButtonGroup.SelectedObject;
            if contains(selectedButton.Text,'Build from scratch')
                layers = imageInputLayer([round(app.ImagesizeEditField_x.Value) round(app.ImagesizeEditField_y.Value) round(app.ImagesizeEditField_z.Value)]); % Minimal input layer
                model2use = layerGraph(layers);
                %deepNetworkDesigner(net);
            elseif contains(selectedButton.Text,'AlexNet')
                model2use = imagePretrainedNetwork("alexnet",NumClasses=round(app.NumberofclassesEditField_2.Value));
            elseif contains(selectedButton.Text,'Inceptionv3')
                model2use = imagePretrainedNetwork("inceptionv3",NumClasses=round(app.NumberofclassesEditField_2.Value));
            elseif contains(selectedButton.Text,'MobileNetv2')
                model2use = imagePretrainedNetwork("mobilenetv2",NumClasses=round(app.NumberofclassesEditField_2.Value));
            elseif contains(selectedButton.Text,'ResNet-50')
                model2use = imagePretrainedNetwork("resnet50",NumClasses=round(app.NumberofclassesEditField_2.Value));
            end
        end

        % Callback function
        function LoaddatapartitionedinfoldersButtonPushed(app, event)

        end

        % Button pushed function: LoaddataButton
        function LoaddataButtonPushed(app, event)
            global imds flag imdsLabels
            dname = uigetdir('C:\');
            %disp(dname)
            if contains(flag, 'Classification')
                if app.LabelssourcefoldernamesCheckBox.Value == 1
                    imds = imageDatastore(dname, ...
                        'IncludeSubfolders',true,'LabelSource','foldernames');
                else
                    imds = imageDatastore(dname, ...
                        'LabelSource','none');
                end
            uialert(app.UIFigure, ['Data loaded.'], 'Success', 'Icon', 'success');
            elseif contains(flag, 'Segmentation')
                imdsImages = imageDatastore(fullfile(dname, 'images'));

                classNamesTxt = app.ClassnamesTextArea.Value{1};
                classNamesTxt = split(classNamesTxt, ';'); %["Background", "Object"];
                pixelValues = 0:length(classNamesTxt)-1;

                imdsLabels = pixelLabelDatastore(fullfile(dname, 'masks'), classNamesTxt, pixelValues);
                %imds = combine(imdsImages,imdsLabels);
                imds = imdsImages;
                uialert(app.UIFigure, ['Data loaded.'], 'Success', 'Icon', 'success');
            end
        end

        % Value changed function: LabelssourcefoldernamesCheckBox
        function LabelssourcefoldernamesCheckBoxValueChanged(app, event)
            value = app.LabelssourcefoldernamesCheckBox.Value;
            if value == 1
                app.LabelssourcetableCheckBox.Value = 0;
                app.ImporttableButton.Enable = 'off';
            else
                app.LabelssourcetableCheckBox.Value = 1;
                app.ImporttableButton.Enable = 'on';
            end
        end

        % Value changed function: LabelssourcetableCheckBox
        function LabelssourcetableCheckBoxValueChanged(app, event)
            value = app.LabelssourcetableCheckBox.Value;
            if value == 1
                app.LabelssourcefoldernamesCheckBox.Value = 0;
                app.ImporttableButton.Enable = 'on';
            else
                app.LabelssourcefoldernamesCheckBox.Value = 1;
                app.ImporttableButton.Enable = 'off';
            end
        end

        % Button pushed function: ImporttableButton
        function ImporttableButtonPushed(app, event)
            global labelsTab
            [file,path] = uigetfile('C:\');
            labelsTab = readtable([path '\' file]);
        end

        % Button pushed function: ApplyButton_2
        function ApplyButton_2Pushed(app, event)
            global imds imdsTrain imdsVal imdsTest imgsTr imgsVl imgsTs flag imdsLabels
            if app.IncludevalidationCheckBox.Value == 1

                if (app.TraindatasetEditField.Value+app.TestdatasetEditField.Value + app.ValidationdatasetEditField.Value) ~= 1
                    msgbox('Ratios does not sum to 1. Make corrections.')
                else

                    if contains(flag, 'Classification')
                        [imdsTrain, imdsVal, imdsTest] = splitEachLabel(imds,app.TraindatasetEditField.Value,app.ValidationdatasetEditField.Value);
                        histrTrain = histogram(app.UIAxes, imdsTrain.Labels, "FaceColor",[1 0 0],"EdgeColor","none");
                        app.TransparencySlider.Visible = 'off';
                        app.TransparencySlider_2.Visible = 'off';
                        app.TransparencySlider_3.Visible = 'off';
                        app.UIAxes.Visible = 'On';
                        app.UIAxes_2.Visible = 'on';
                        app.UIAxes_3.Visible = 'on';
                        app.Label.Visible = 'on';
                        app.Label_2.Visible = 'on';
                        app.Label_3.Visible = 'on';
                        app.ClassLabel.Visible = 'on'; app.ClassLabel.Text = "Class: ";
                        app.ClassLabel_2.Visible = 'on'; app.ClassLabel_2.Text = "Class: ";
                        app.ClassLabel_3.Visible = 'on'; app.ClassLabel_3.Text = "Class: ";

                        histrVal = histogram(app.UIAxes_2, imdsVal.Labels, "FaceColor",[1 0 0],"EdgeColor","none");
                        histrTest = histogram(app.UIAxes_3, imdsTest.Labels, "FaceColor",[1 0 0],"EdgeColor","none");
                        imgsTr = readall(imdsTrain);
                        imgsVl = readall(imdsVal);
                        imgsTs = readall(imdsTest);

                        imshow(imgsTr{1}, 'Parent', app.UIAxes2)
                        app.Label.Text = [string(imdsTrain.Labels(1))];
                        app.Slider.Value = 1
                        app.Slider.Limits = [1 length(imgsTr)]

                        imshow(imgsVl{1}, 'Parent', app.UIAxes2_2)
                        app.Label_2.Text = [string(imdsVal.Labels(1))];
                        app.Slider_2.Value = 1
                        app.Slider_2.Limits = [1 length(imgsVl)]

                        imshow(imgsTs{1}, 'Parent', app.UIAxes2_3)
                        app.Label_3.Text = [string(imdsTest.Labels(1))];
                        app.Slider_3.Value = 1
                        app.Slider_3.Limits = [1 length(imgsTs)]
                    elseif contains(flag, 'Segmentation')
                        trainRatio = app.TraindatasetEditField.Value;
                        valRatio = app.ValidationdatasetEditField.Value;
                        testRatio = app.TestdatasetEditField.Value;

                        % Count total number of images
                        numImages = numel(imds.Files);
                        numTrain = round(trainRatio * numImages);
                        numVal = round(valRatio * numImages);
                        numTest = numImages - (numTrain + numVal); % Ensure full coverage

                        % Shuffle indices for randomness
                        idx = randperm(numImages);
                        global imdsTrain_im imdsVal_im imdsTest_im pxdsTrain pxdsVal pxdsTest
                        % Split imageDatastore
                        imdsTrain_im = subset(imds, idx(1:numTrain));
                        imdsVal_im   = subset(imds, idx(numTrain+1:numTrain+numVal));
                        imdsTest_im  = subset(imds, idx(numTrain+numVal+1:end));

                        % Split pixelLabelDatastore (for segmentation masks)
                        pxdsTrain = subset(imdsLabels, idx(1:numTrain));
                        pxdsVal   = subset(imdsLabels, idx(numTrain+1:numTrain+numVal));
                        pxdsTest  = subset(imdsLabels, idx(numTrain+numVal+1:end));

                        % Combine images and labels to maintain alignment
                        imdsTrain = combine(imdsTrain_im, pxdsTrain);
                        imdsVal = combine(imdsVal_im, pxdsVal);
                        imdsTest = combine(imdsTest_im, pxdsTest);

                        app.TransparencySlider.Visible = 'on';
                        app.TransparencySlider_2.Visible = 'on';
                        app.TransparencySlider_3.Visible = 'on';

                        app.UIAxes.Visible = 'off';
                        app.UIAxes_2.Visible = 'off';
                        app.UIAxes_3.Visible = 'off';
                        app.Label.Visible = 'off';
                        app.Label_2.Visible = 'off';
                        app.Label_3.Visible = 'off';
                        app.ClassLabel.Visible = 'off';
                        app.ClassLabel_2.Visible = 'off';
                        app.ClassLabel_3.Visible = 'off';
                        
                        clear imdsTrain_im_ra imdsVal_im_ra imdsTest_im_ra
                        % imdsTrain_im_ra = readall(imdsTrain_im);
                        % imdsVal_im_ra = readall(imdsVal_im);
                        % imdsTest_im_ra = readall(imdsTest_im);
                        % 
                        % pxdsTrain_ra = readall(pxdsTrain);
                        % pxdsVal_ra = readall(pxdsVal);
                        % pxdsTest_ra = readall(pxdsTest);

                        index = 1;  % Specify the index of the image you want to read (e.g., 5th image)
                        imageFilePathTr = imdsTrain_im.Files{index};
                        imdsTrain_im_ra = imread(imageFilePathTr);

                        imageFilePathVal = imdsVal_im.Files{index};
                        imdsVal_im_ra = imread(imageFilePathVal);

                        imageFilePathTest = imdsTest_im.Files{index};
                        imdsTest_im_ra = imread(imageFilePathTest);

                        pixFilePathTr = pxdsTrain.Files{index};
                        pxdsTrain_ra = imread(pixFilePathTr);
                        pixFilePathVal = pxdsVal.Files{index};
                        pxdsVal_ra = imread(pixFilePathVal);
                        pixFilePathTest = pxdsTest.Files{index};
                        pxdsTest_ra = imread(pixFilePathTest);


                        imshow(labeloverlay(imdsTrain_im_ra, pxdsTrain_ra, 'Transparency', app.TransparencySlider.Value), 'Parent', app.UIAxes2)
                        app.Slider.Value = 1
                        app.Slider.Limits = [1 length(imgsTr)]

                        imshow(labeloverlay(imdsVal_im_ra, pxdsVal_ra, 'Transparency', app.TransparencySlider_2.Value), 'Parent', app.UIAxes2_2)
                        app.Slider_2.Value = 1
                        app.Slider_2.Limits = [1 length(imgsVl)]

                        imshow(labeloverlay(imdsTest_im_ra, pxdsTest_ra, 'Transparency', app.TransparencySlider_3.Value), 'Parent', app.UIAxes2_3)
                        app.Slider_3.Value = 1
                        app.Slider_3.Limits = [1 length(imgsTs)]

                    end
                end


            else
                if (app.TraindatasetEditField.Value+app.TestdatasetEditField.Value) ~= 1
                    msgbox('Ratios does not sum to 1. Make corrections.')
                else
                    [imdsTrain, imdsTest] = splitEachLabel(imds,app.TraindatasetEditField.Value);
                    imdsVal = [];
                    histrTrain = histogram(app.UIAxes, imdsTrain.Labels, "FaceColor",[1 0 0],"EdgeColor","none");
                    app.UIAxes.Visible = 'Off';
                    histrTest = histogram(app.UIAxes_3, imdsTest.Labels, "FaceColor",[1 0 0],"EdgeColor","none");
                end
            end
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            global imgsTr imgsVl imgsTs imdsTrain imdsVal imdsTest flag
            value = app.Slider.Value;
            if contains(flag, 'Classification')
                imgsTr = readall(imdsTrain);
                imshow(imgsTr{round(value)}, 'Parent', app.UIAxes2)
                app.Label.Text = [string(imdsTrain.Labels(round(value)))];
            elseif contains(flag, 'Segmentation')
                global imdsTrain_im imdsVal_im imdsTest_im pxdsTrain pxdsVal pxdsTest
                app.TransparencySlider.Visible = 'on';
                app.TransparencySlider_2.Visible = 'on';
                app.TransparencySlider_3.Visible = 'on';

                app.UIAxes.Visible = 'off';
                app.UIAxes_2.Visible = 'off';
                app.UIAxes_3.Visible = 'off';
                app.Label.Visible = 'off';
                app.Label_2.Visible = 'off';
                app.Label_3.Visible = 'off';
                app.ClassLabel.Visible = 'off';
                app.ClassLabel_2.Visible = 'off';
                app.ClassLabel_3.Visible = 'off';

                
                index = round(value);  % Specify the index of the image you want to read (e.g., 5th image)
                imageFilePathTr = imdsTrain_im.Files{index};
                imdsTrain_im_ra = imread(imageFilePathTr);
                
                pixFilePathTr = pxdsTrain.Files{index};
                pxdsTrain_ra = imread(pixFilePathTr);

                imshow(labeloverlay(imdsTrain_im_ra, pxdsTrain_ra, 'Transparency', app.TransparencySlider.Value), 'Parent', app.UIAxes2)
            end
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            global imgsTr imgsVl imgsTs imdsTrain imdsVal imdsTest flag
            if contains(flag, 'Classification')
                imgsTr = readall(imdsTrain);
                imshow(imgsTr{round(changingValue)}, 'Parent', app.UIAxes2)
                app.Label.Text = [string(imdsTrain.Labels(round(changingValue)))];
            elseif contains(flag, 'Segmentation')
                global imdsTrain_im imdsVal_im imdsTest_im pxdsTrain pxdsVal pxdsTest
                app.TransparencySlider.Visible = 'on';
                app.TransparencySlider_2.Visible = 'on';
                app.TransparencySlider_3.Visible = 'on';

                app.UIAxes.Visible = 'off';
                app.UIAxes_2.Visible = 'off';
                app.UIAxes_3.Visible = 'off';
                app.Label.Visible = 'off';
                app.Label_2.Visible = 'off';
                app.Label_3.Visible = 'off';
                app.ClassLabel.Visible = 'off';
                app.ClassLabel_2.Visible = 'off';
                app.ClassLabel_3.Visible = 'off';

                index = round(changingValue);  % Specify the index of the image you want to read (e.g., 5th image)
                imageFilePathTr = imdsTrain_im.Files{index};
                imdsTrain_im_ra = imread(imageFilePathTr);
                
                pixFilePathTr = pxdsTrain.Files{index};
                pxdsTrain_ra = imread(pixFilePathTr);
                imshow(labeloverlay(imdsTrain_im_ra, pxdsTrain_ra, 'Transparency', app.TransparencySlider.Value), 'Parent', app.UIAxes2)
            end
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            value = app.Slider_2.Value;
            global imgsTr imgsVl imgsTs imdsTrain imdsVal imdsTest flag
             if contains(flag, 'Classification')
                imgsVl = readall(imdsVal);
    
                imshow(imgsVl{round(value)}, 'Parent', app.UIAxes2_2)
                app.Label_2.Text = [string(imdsVal.Labels(round(value)))];

            elseif contains(flag, 'Segmentation')
                global imdsTrain_im imdsVal_im imdsTest_im pxdsTrain pxdsVal pxdsTest
                app.TransparencySlider.Visible = 'on';
                app.TransparencySlider_2.Visible = 'on';
                app.TransparencySlider_3.Visible = 'on';

                app.UIAxes.Visible = 'off';
                app.UIAxes_2.Visible = 'off';
                app.UIAxes_3.Visible = 'off';
                app.Label.Visible = 'off';
                app.Label_2.Visible = 'off';
                app.Label_3.Visible = 'off';
                app.ClassLabel.Visible = 'off';
                app.ClassLabel_2.Visible = 'off';
                app.ClassLabel_3.Visible = 'off';

                index = round(value);  % Specify the index of the image you want to read (e.g., 5th image)
                imageFilePathVal = imdsVal_im.Files{index};
                imdsVal_im_ra = imread(imageFilePathVal);
                pixFilePathVal = pxdsVal.Files{index};
                pxdsVal_ra = imread(pixFilePathVal);

                imshow(labeloverlay(imdsVal_im_ra, pxdsVal_ra, 'Transparency', app.TransparencySlider.Value), 'Parent', app.UIAxes2_2)
             end

        end

        % Value changing function: Slider_2
        function Slider_2ValueChanging(app, event)
            changingValue = event.Value;
            global imgsTr imgsVl imgsTs imdsTrain imdsVal imdsTest flag
             if contains(flag, 'Classification')
                imgsVl = readall(imdsVal);
    
                imshow(imgsVl{round(changingValue)}, 'Parent', app.UIAxes2_2)
                app.Label_2.Text = [string(imdsVal.Labels(1))];

            elseif contains(flag, 'Segmentation')
                global imdsTrain_im imdsVal_im imdsTest_im pxdsTrain pxdsVal pxdsTest
                app.TransparencySlider.Visible = 'on';
                app.TransparencySlider_2.Visible = 'on';
                app.TransparencySlider_3.Visible = 'on';

                app.UIAxes.Visible = 'off';
                app.UIAxes_2.Visible = 'off';
                app.UIAxes_3.Visible = 'off';
                app.Label.Visible = 'off';
                app.Label_2.Visible = 'off';
                app.Label_3.Visible = 'off';
                app.ClassLabel.Visible = 'off';
                app.ClassLabel_2.Visible = 'off';
                app.ClassLabel_3.Visible = 'off';

                index = round(changingValue);  % Specify the index of the image you want to read (e.g., 5th image)
                imageFilePathVal = imdsVal_im.Files{index};
                imdsVal_im_ra = imread(imageFilePathVal);
                pixFilePathVal = pxdsVal.Files{index};
                pxdsVal_ra = imread(pixFilePathVal);

                imshow(labeloverlay(imdsVal_im_ra, pxdsVal_ra, 'Transparency', app.TransparencySlider.Value), 'Parent', app.UIAxes2_2)
             end
        end

        % Value changed function: Slider_3
        function Slider_3ValueChanged(app, event)
            value = app.Slider_3.Value;
            global imgsTr imgsVl imgsTs imdsTrain imdsVal imdsTest flag
            if contains(flag, 'Classification')
                imgsTs = readall(imdsTest);
                imshow(imgsTs{round(value)}, 'Parent', app.UIAxes2_3)
                app.Label_3.Text = [string(imdsTest.Labels(round(value)))];
            elseif contains(flag, 'Segmentation')
                global imdsTrain_im imdsVal_im imdsTest_im pxdsTrain pxdsVal pxdsTest
                app.TransparencySlider.Visible = 'on';
                app.TransparencySlider_2.Visible = 'on';
                app.TransparencySlider_3.Visible = 'on';

                app.UIAxes.Visible = 'off';
                app.UIAxes_2.Visible = 'off';
                app.UIAxes_3.Visible = 'off';
                app.Label.Visible = 'off';
                app.Label_2.Visible = 'off';
                app.Label_3.Visible = 'off';
                app.ClassLabel.Visible = 'off';
                app.ClassLabel_2.Visible = 'off';
                app.ClassLabel_3.Visible = 'off';

                index = round(value);  % Specify the index of the image you want to read (e.g., 5th image)
                imageFilePathTest = imdsTest_im.Files{index};
                imdsTest_im_ra = imread(imageFilePathTest);
                pixFilePathTest = pxdsTest.Files{index};
                pxdsTest_ra = imread(pixFilePathTest);

                imshow(labeloverlay(imdsTest_im_ra, pxdsTest_ra, 'Transparency', app.TransparencySlider.Value), 'Parent', app.UIAxes2_3)
             end

        end

        % Value changing function: Slider_3
        function Slider_3ValueChanging(app, event)
            changingValue = event.Value;
            global imgsTr  imgsTs imdsTrain imdsVal imdsTest flag
            if contains(flag, 'Classification')
                imgsTs = readall(imdsTest);
                imshow(imgsTs{round(changingValue)}, 'Parent', app.UIAxes2_3)
                app.Label_3.Text = [string(imdsTest.Labels(round(changingValue)))];
            elseif contains(flag, 'Segmentation')
                global imdsTrain_im imdsVal_im imdsTest_im pxdsTrain pxdsVal pxdsTest
                app.TransparencySlider.Visible = 'on';
                app.TransparencySlider_2.Visible = 'on';
                app.TransparencySlider_3.Visible = 'on';

                app.UIAxes.Visible = 'off';
                app.UIAxes_2.Visible = 'off';
                app.UIAxes_3.Visible = 'off';
                app.Label.Visible = 'off';
                app.Label_2.Visible = 'off';
                app.Label_3.Visible = 'off';
                app.ClassLabel.Visible = 'off';
                app.ClassLabel_2.Visible = 'off';
                app.ClassLabel_3.Visible = 'off';

                index = round(changingValue);  % Specify the index of the image you want to read (e.g., 5th image)
                imageFilePathTest = imdsTest_im.Files{index};
                imdsTest_im_ra = imread(imageFilePathTest);
                pixFilePathTest = pxdsTest.Files{index};
                pxdsTest_ra = imread(pixFilePathTest);

                imshow(labeloverlay(imdsTest_im_ra, pxdsTest_ra, 'Transparency', app.TransparencySlider.Value), 'Parent', app.UIAxes2_3)
              end
        end

        % Button down function: DataaugmentationTab
        function DataaugmentationTabButtonDown(app, event)
            % msgbox('Please wait...')
            % tabs_da = readtable('./dataaugment.xlsx');
            % app.UITable.Data = tabs_da;

        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            global flag
            selectedTab = app.TabGroup.SelectedTab;
            if contains(selectedTab.Title, 'Data augmentation')
                tabs_da = readtable([pwd '\dataaugment.xlsx']);
                idx = find(contains(tabs_da.Apply,'true'));
                for ix = 1:length(idx)
                    tabs_da.Apply{idx(ix)} = true;
                end
                app.UITable.Data = tabs_da;
                if contains(flag, 'Classification')
                    app.PanelClassification.Visible = 'on';
                    app.PanelSegmentation.Visible = 'off';
                elseif contains(flag, 'Segmentation')
                    app.PanelClassification.Visible = 'off';
                    app.PanelSegmentation.Visible = 'on';
                end
            elseif contains(selectedTab.Title, 'Data')
                app.TransparencySlider.Visible = 'off';
                app.TransparencySlider_2.Visible = 'off';
                app.TransparencySlider_3.Visible = 'off';
            elseif contains(selectedTab.Title, 'Train')
                xlabel(app.UIAxesLoss, 'Iterations');
                ylabel(app.UIAxesLoss, 'Loss');
                title(app.UIAxesLoss, 'Training Loss');
                hold(app.UIAxesLoss, 'on');

                xlabel(app.UIAxesAccuracy, 'Iterations');
                ylabel(app.UIAxesAccuracy, 'Accuracy');
                title(app.UIAxesAccuracy, 'Training Accuracy');
                hold(app.UIAxesAccuracy, 'on');

                varNames = evalin('base', 'who');
                searchStr = 'layers_';
                % Get all variable names in the workspace
                matchingVars = varNames(contains(varNames, searchStr));
                app.ModeltotrainDropDown.Items = matchingVars;

                val_opt = app.OptimizerDropDown.Value;

                if contains(val_opt, 'sgdm')
                    tabs_hp = readtable('./trainingOpts_sgdm.xlsx');
                    app.UITable2.Data = tabs_hp;
                elseif contains(val_opt, 'adam')
                    tabs_hp = readtable('./trainingOpts_adam.xlsx');
                    app.UITable2.Data = tabs_hp;
                elseif contains(val_opt, 'rmsprop')
                    tabs_hp = readtable('./trainingOpts_rmsprop.xlsx');
                    app.UITable2.Data = tabs_hp;
                elseif contains(val_opt, 'lbfgs')
                    tabs_hp = readtable('./trainingOpts_lbfgs.xlsx');
                    app.UITable2.Data = tabs_hp;
                end
            elseif contains(selectedTab.Title, 'Test')
                mods = [];
                all_models = dir(['./models/*.mat']);
                mods{1} = '';
                for jk = 2:length(all_models)+1
                    mods{jk} = all_models(jk-1).name(1:end-4);
                end

                if isempty(mods)
                    app.SelectsavedmodelDropDown.Items = {'Train your model first.'};
                    app.SelectsavedmodelDropDown.Value = 'Train your model first.';
                    app.SelectsavedmodelDropDown.Enable = 'off';
                else
                    app.SelectsavedmodelDropDown.Enable = 'on';
                    app.SelectsavedmodelDropDown.Items = mods;
                    app.SelectsavedmodelDropDown.Value = '';
                end
            end
        end

        % Selection changed function: UITable
        function UITableSelectionChanged(app, event)
            selection = app.UITable.Selection;
            disp('ok')
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            disp('ok')

        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            global imgsTr imgsVl imgsTs imdsTrain imdsVal imdsTest augmenter model2use
            indices = event.Indices;
            newData = event.NewData;
            augmenterSettings = struct(); % Initialize an empty struct

            for ix = 1:length(app.UITable.Data.Apply)
                if app.UITable.Data.Apply{ix} == 1
                    data_f = app.UITable.Data.Value{ix};
                    data_f = data_f(find(~isspace(data_f)));
                    data_f = strsplit(data_f, ',');
                    if length(data_f) > 1
                        val_data = [str2num(data_f{1}) str2num(data_f{2})];
                    else
                        val_data = str2num(data_f{1});
                    end
                    augmenterSettings(1).(app.UITable.Data.Operation{ix}) = val_data;
                end
            end

            augmenter = imageDataAugmenter(augmenterSettings);
            auimds = augmentedImageDatastore([126 126 3], imdsTrain, 'DataAugmentation', augmenter)
            dataOut = read(auimds);
            dataOut_in = dataOut.input;
            imshow(dataOut_in{1}, 'Parent', app.UIAxes4)
            imshow(dataOut_in{2}, 'Parent', app.UIAxes4_2)
            imshow(dataOut_in{3}, 'Parent', app.UIAxes4_3)
            imshow(dataOut_in{4}, 'Parent', app.UIAxes4_4)
            imshow(dataOut_in{5}, 'Parent', app.UIAxes4_5)
            imshow(dataOut_in{6}, 'Parent', app.UIAxes4_6)
            imshow(dataOut_in{7}, 'Parent', app.UIAxes4_7)

            % "sgdm", ...
            %     LearnRateSchedule="piecewise", ...
            %     LearnRateDropFactor=0.2, ...
            %     LearnRateDropPeriod=5, ...
            %     MaxEpochs=20, ...
            %     MiniBatchSize=64, ...
            %     Plots="training-progress"

        end

        % Value changed function: OptimizerDropDown
        function OptimizerDropDownValueChanged(app, event)
            val_opt = app.OptimizerDropDown.Value;
            if contains(val_opt, 'sgdm')
                tabs_hp = readtable('./trainingOpts_sgdm.xlsx');
                app.UITable2.Data = tabs_hp;
            elseif contains(val_opt, 'adam')
                tabs_hp = readtable('./trainingOpts_adam.xlsx');
                app.UITable2.Data = tabs_hp;
            elseif contains(val_opt, 'rmsprop')
                tabs_hp = readtable('./trainingOpts_rmsprop.xlsx');
                app.UITable2.Data = tabs_hp;
            elseif contains(val_opt, 'lbfgs')
                tabs_hp = readtable('./trainingOpts_lbfgs.xlsx');
                app.UITable2.Data = tabs_hp;
            end
        end

        % Button pushed function: TrainButton
        function TrainButtonPushed(app, event)
            global imgsTr imgsVl imgsTs imdsTrain imdsVal imdsTest augmenter model2use options
            global allTL allTA
            allTL = [];
            allTA = [];
            layers = evalin('base', app.ModeltotrainDropDown.Value);
            %
            % layers = [
            %     imageInputLayer([128 128 3])
            %     convolution2dLayer(3,8,Padding="same")
            %     batchNormalizationLayer
            %     reluLayer
            %     fullyConnectedLayer(2)
            %     softmaxLayer];
            model2use = dlnetwork(layers);

            % Clear previous plots
            cla(app.UIAxesLoss);
            cla(app.UIAxesAccuracy);

            % Set labels and titles
            xlabel(app.UIAxesLoss, 'Iterations');
            ylabel(app.UIAxesLoss, 'Loss');
            title(app.UIAxesLoss, 'Training Loss');
            hold(app.UIAxesLoss, 'on');

            xlabel(app.UIAxesAccuracy, 'Iterations');
            ylabel(app.UIAxesAccuracy, 'Accuracy');
            title(app.UIAxesAccuracy, 'Training Accuracy');
            hold(app.UIAxesAccuracy, 'on');

            imgSize = model2use.Layers(1).InputSize;

            % Apply transformations to training dataset
            auimdsTrain = augmentedImageDatastore(imgSize, imdsTrain, 'DataAugmentation', augmenter);

            % Apply transformations to validation dataset if needed
            if app.IncludevalidationCheckBox.Value == 1
                auimdsVal = augmentedImageDatastore(imgSize, imdsVal, 'DataAugmentation', augmenter);
                % Optional: Ensure that validation data has the same transformation
                % auimdsVal = transform(auimdsVal, @(x) repmat(x, [1 1 1]));  % Ensure it's [height, width, channels]
            end

            trainingOpts = struct();

            for ix = 1:length(app.UITable2.Data.Hyperparameter)

                data_f = app.UITable2.Data.Value{ix};
                if isnan(str2double(data_f))
                    trainingOpts(1).(app.UITable2.Data.Hyperparameter{ix}) = erase(string(data_f),"'");
                else
                    trainingOpts(1).(app.UITable2.Data.Hyperparameter{ix}) = str2double(data_f);
                end

            end

            % Add ValidationData only if the checkbox is checked
            if app.IncludevalidationCheckBox.Value == 1
                trainingOpts(1).('ValidationData') = auimdsVal;
            end
            trainingOpts(1).('OutputFcn') = @(info) updatePlot(app, info);
            options = trainingOptions(string(app.OptimizerDropDown.Value), trainingOpts, Metrics="accuracy");


            app.TrainingwaitingtostartLabel.Text = ['Training: in progress, please wait... Epoch: 0 of ' num2str(trainingOpts.MaxEpochs)];
            netnet = trainnet(auimdsTrain, model2use, string(app.LossfunctionDropDown.Value),options);
            % save('./models/',netnet)
            msgfig = msgbox('Training done.','Success','modal');
            app.askForFilenameAndSave(netnet)
            app.TrainingwaitingtostartLabel.Text = ['Training: finished. Epoch: ' num2str(trainingOpts.MaxEpochs) ' of ' num2str(trainingOpts.MaxEpochs)];

        end

        % Callback function: PushTool
        function PushToolClicked(app, event)
            global projectName
        end

        % Value changed function: SelectsavedmodelDropDown
        function SelectsavedmodelDropDownValueChanged(app, event)
            global imdsTest

            imT = readall(imdsTest);
            imshow(imT{1}, 'Parent', app.UIAxes4_8)
            %app.Label.Text = [string(imdsTrain.Labels(1))];
            app.Slider_4.Value = 1
            app.Slider_4.Limits = [1 length(imT)]

            value = app.SelectsavedmodelDropDown.Value;

            loaded_net = load("./models/"+string(value)+".mat");

            XTest = readall(imdsTest);
            TTest = imdsTest.Labels;
            classNames = categories(TTest);

            XTest = cat(4,XTest{:});
            XTest = single(XTest);

            YTest = predict(loaded_net.trainedNet,XTest);
            YTest = onehotdecode(YTest,classNames,2)

            confMat = confusionmat(YTest, TTest);

            imagesc(app.UIAxesConfMat, confMat);
            colormap(app.UIAxesConfMat, abyss);  % Change to 'hot', 'jet', 'gray', etc.
            confChart.Title = 'Confusion Matrix';
            confChart.XLabel = 'Predicted Class';
            confChart.YLabel = 'Actual Class';

            colorbar(app.UIAxesConfMat, 'off');  % Hides the colorbar

            for i = 1:size(confMat, 1)  % Rows
                for j = 1:size(confMat, 2)  % Columns
                    text(app.UIAxesConfMat, j, i, num2str(confMat(i, j)), ...
                        'Color', 'white', ...   % Text color (change for visibility)
                        'FontSize', 12, ...
                        'FontWeight', 'bold', ...
                        'HorizontalAlignment', 'center');
                end
            end
        end

        % Button pushed function: GradCAMButton
        function GradCAMButtonPushed(app, event)
            global imdsTest
            value = app.SelectsavedmodelDropDown.Value;
            loaded_net = load("./models/"+string(value)+".mat");

            valueSlider = app.Slider_4.Value;
            imgsTs = readall(imdsTest);
            TTest = imdsTest.Labels;

            %scoreMap = gradCAM(loaded_net.trainedNet,imgsTs{round(valueSlider)},TTest{round(valueSlider)},ReductionLayer="prob");
            scoreMap = gradCAM(loaded_net.trainedNet, imgsTs{round(valueSlider)}, TTest(round(valueSlider)));
            imshow(imgsTs{round(valueSlider)}, 'Parent', app.UIAxes4_9);
            hold(app.UIAxes, 'on');  % Keep the axes active for overlay

            % Overlay score map with transparency
            h = imagesc(app.UIAxes4_9, scoreMap, 'AlphaData', 0.2);
            colormap(app.UIAxes4_9, 'jet');  % Apply 'jet' colormap to scoreMap

            % Adjust properties of the axes as needed
            axis(app.UIAxes4_9, 'image');  % Ensure the aspect ratio is correct

        end

        % Value changing function: Slider_4
        function Slider_4ValueChanging(app, event)
            changingValue = event.Value;
            cla(app.UIAxes4_9);
            global imdsTest
            value = app.Slider_4.Value;
            value = app.SelectsavedmodelDropDown.Value;
            loaded_net = load("./models/"+string(value)+".mat");

            valueSlider = app.Slider_4.Value;
            imgsTs = readall(imdsTest);
            TTest = imdsTest.Labels;
            imshow(imgsTs{round(changingValue)}, 'Parent', app.UIAxes4_8);
        end

        % Value changed function: Slider_4
        function Slider_4ValueChanged(app, event)
            global imdsTest
            valueSli = app.Slider_4.Value;
            value = app.SelectsavedmodelDropDown.Value;
            loaded_net = load("./models/"+string(value)+".mat");

            valueSlider = app.Slider_4.Value;
            imgsTs = readall(imdsTest);
            TTest = imdsTest.Labels;
            imshow(imgsTs{round(valueSli)}, 'Parent', app.UIAxes4_8);
        end

        % Selection change function: TabGroup2
        function TabGroup2SelectionChanged(app, event)
            selectedTab = app.TabGroup2.SelectedTab;

        end

        % Selection changed function: SelectmodelButtonGroup_2
        function SelectmodelButtonGroup_2SelectionChanged(app, event)
            global model2use
            selectedButton = app.SelectmodelButtonGroup_2.SelectedObject;
            if contains(selectedButton.Text,'Build from scratch')
                layers = imageInputLayer([round(app.ImagesizeEditField_x_s.Value) round(app.ImagesizeEditField_y_s.Value) round(app.ImagesizeEditField_z_s.Value)]); % Minimal input layer
                model2use = layerGraph(layers);
                app.BackboneDropDown.Enable = 'off';
                %deepNetworkDesigner(net);
            elseif contains(selectedButton.Text,'DeepLabv3+')
                app.BackboneDropDown.Enable = 'on';
                if contains( app.BackboneDropDown.Value, 'ResNet50')
                    bckbone = 'resnet50';
                end
                model2use = deeplabv3plus([round(app.ImagesizeEditField_x_s.Value) round(app.ImagesizeEditField_y_s.Value) round(app.ImagesizeEditField_z_s.Value)], round(app.NumberofclassesEditField.Value),bckbone);
            elseif contains(selectedButton.Text,'Inceptionv3')
                model2use = unet([round(app.ImagesizeEditField_x_s.Value) round(app.ImagesizeEditField_y_s.Value) round(app.ImagesizeEditField_z_s.Value)], round(app.NumberofclassesEditField.Value));
            end
        end

        % Value changed function: TransparencySlider
        function TransparencySliderValueChanged(app, event)
            valueTr = app.TransparencySlider.Value;
            value = app.Slider.Value;
            global imgsTr  imgsTs imdsTrain imdsVal imdsTest flag
            if contains(flag, 'Segmentation')
                global imdsTrain_im imdsVal_im imdsTest_im pxdsTrain pxdsVal pxdsTest
                app.TransparencySlider.Visible = 'on';
                app.TransparencySlider_2.Visible = 'on';
                app.TransparencySlider_3.Visible = 'on';

                app.UIAxes.Visible = 'off';
                app.UIAxes_2.Visible = 'off';
                app.UIAxes_3.Visible = 'off';
                app.Label.Visible = 'off';
                app.Label_2.Visible = 'off';
                app.Label_3.Visible = 'off';
                app.ClassLabel.Visible = 'off';
                app.ClassLabel_2.Visible = 'off';
                app.ClassLabel_3.Visible = 'off';

                index = round(value);  % Specify the index of the image you want to read (e.g., 5th image)
                imageFilePathTr = imdsTrain_im.Files{index};
                imdsTrain_im_ra = imread(imageFilePathTr);
                pixFilePathTr = pxdsTrain.Files{index};
                pxdsTrain_ra = imread(pixFilePathTr);
                imshow(labeloverlay(imdsTrain_im_ra, pxdsTrain_ra, 'Transparency', valueTr), 'Parent', app.UIAxes2)
             end
        end

        % Value changing function: TransparencySlider
        function TransparencySliderValueChanging(app, event)
            changingValue = event.Value;
            value = app.Slider.Value;
            global imgsTr  imgsTs imdsTrain imdsVal imdsTest flag
            if contains(flag, 'Segmentation')
                global imdsTrain_im imdsVal_im imdsTest_im pxdsTrain pxdsVal pxdsTest
                app.TransparencySlider.Visible = 'on';
                app.TransparencySlider_2.Visible = 'on';
                app.TransparencySlider_3.Visible = 'on';

                app.UIAxes.Visible = 'off';
                app.UIAxes_2.Visible = 'off';
                app.UIAxes_3.Visible = 'off';
                app.Label.Visible = 'off';
                app.Label_2.Visible = 'off';
                app.Label_3.Visible = 'off';
                app.ClassLabel.Visible = 'off';
                app.ClassLabel_2.Visible = 'off';
                app.ClassLabel_3.Visible = 'off';

                index = round(value);  % Specify the index of the image you want to read (e.g., 5th image)
                imageFilePathTr = imdsTrain_im.Files{index};
                imdsTrain_im_ra = imread(imageFilePathTr);
                pixFilePathTr = pxdsTrain.Files{index};
                pxdsTrain_ra = imread(pixFilePathTr);
                imshow(labeloverlay(imdsTrain_im_ra, pxdsTrain_ra, 'Transparency', changingValue), 'Parent', app.UIAxes2)
            end
        end

        % Value changed function: TransparencySlider_2
        function TransparencySlider_2ValueChanged(app, event)
            valueTr = app.TransparencySlider_2.Value;
            value = app.Slider_2.Value;
            global imgsTr  imgsTs imdsTrain imdsVal imdsTest flag
            if contains(flag, 'Segmentation')
                global imdsTrain_im imdsVal_im imdsTest_im pxdsTrain pxdsVal pxdsTest
                app.TransparencySlider.Visible = 'on';
                app.TransparencySlider_2.Visible = 'on';
                app.TransparencySlider_3.Visible = 'on';

                app.UIAxes.Visible = 'off';
                app.UIAxes_2.Visible = 'off';
                app.UIAxes_3.Visible = 'off';
                app.Label.Visible = 'off';
                app.Label_2.Visible = 'off';
                app.Label_3.Visible = 'off';
                app.ClassLabel.Visible = 'off';
                app.ClassLabel_2.Visible = 'off';
                app.ClassLabel_3.Visible = 'off';

                index = round(value);  % Specify the index of the image you want to read (e.g., 5th image)

                imageFilePathVal = imdsVal_im.Files{index};
                imdsVal_im_ra = imread(imageFilePathVal);
                pixFilePathVal = pxdsVal.Files{index};
                pxdsVal_ra = imread(pixFilePathVal);

                imshow(labeloverlay(imdsVal_im_ra, imdsVal_im_ra, 'Transparency', valueTr), 'Parent', app.UIAxes2_2)
             end
        end

        % Value changing function: TransparencySlider_2
        function TransparencySlider_2ValueChanging(app, event)
            changingValue = event.Value;
            value = app.Slider_2.Value;
            global imgsTr  imgsTs imdsTrain imdsVal imdsTest flag
            if contains(flag, 'Segmentation')
                global imdsTrain_im imdsVal_im imdsTest_im pxdsTrain pxdsVal pxdsTest
                app.TransparencySlider.Visible = 'on';
                app.TransparencySlider_2.Visible = 'on';
                app.TransparencySlider_3.Visible = 'on';

                app.UIAxes.Visible = 'off';
                app.UIAxes_2.Visible = 'off';
                app.UIAxes_3.Visible = 'off';
                app.Label.Visible = 'off';
                app.Label_2.Visible = 'off';
                app.Label_3.Visible = 'off';
                app.ClassLabel.Visible = 'off';
                app.ClassLabel_2.Visible = 'off';
                app.ClassLabel_3.Visible = 'off';

                index = round(value);  % Specify the index of the image you want to read (e.g., 5th image)

                imageFilePathVal = imdsVal_im.Files{index};
                imdsVal_im_ra = imread(imageFilePathVal);
                pixFilePathVal = pxdsVal.Files{index};
                pxdsVal_ra = imread(pixFilePathVal);

                imshow(labeloverlay(imdsVal_im_ra, imdsVal_im_ra, 'Transparency', changingValue), 'Parent', app.UIAxes2_2)
             end
        end

        % Value changed function: TransparencySlider_3
        function TransparencySlider_3ValueChanged(app, event)
            changingValue = app.TransparencySlider_3.Value;
            value = app.Slider_3.Value;
            global imgsTr  imgsTs imdsTrain imdsVal imdsTest flag
            if contains(flag, 'Segmentation')
                global imdsTrain_im imdsVal_im imdsTest_im pxdsTrain pxdsVal pxdsTest
                app.TransparencySlider.Visible = 'on';
                app.TransparencySlider_2.Visible = 'on';
                app.TransparencySlider_3.Visible = 'on';

                app.UIAxes.Visible = 'off';
                app.UIAxes_2.Visible = 'off';
                app.UIAxes_3.Visible = 'off';
                app.Label.Visible = 'off';
                app.Label_2.Visible = 'off';
                app.Label_3.Visible = 'off';
                app.ClassLabel.Visible = 'off';
                app.ClassLabel_2.Visible = 'off';
                app.ClassLabel_3.Visible = 'off';

                index = round(value);  % Specify the index of the image you want to read (e.g., 5th image)

                imageFilePathTest = imdsTest_im.Files{index};
                imdsTest_im_ra = imread(imageFilePathTest);
                pixFilePathTest = pxdsTest.Files{index};
                pxdsTest_ra = imread(pixFilePathTest);
                imshow(labeloverlay(imdsTest_im_ra, pxdsTest_ra, 'Transparency', valueTr), 'Parent', app.UIAxes2_2)
             end
        end

        % Value changing function: TransparencySlider_3
        function TransparencySlider_3ValueChanging(app, event)
            changingValue = event.Value;
            value = app.Slider_3.Value;
            global imgsTr  imgsTs imdsTrain imdsVal imdsTest flag
            if contains(flag, 'Segmentation')
                global imdsTrain_im imdsVal_im imdsTest_im pxdsTrain pxdsVal pxdsTest
                app.TransparencySlider.Visible = 'on';
                app.TransparencySlider_2.Visible = 'on';
                app.TransparencySlider_3.Visible = 'on';

                app.UIAxes.Visible = 'off';
                app.UIAxes_2.Visible = 'off';
                app.UIAxes_3.Visible = 'off';
                app.Label.Visible = 'off';
                app.Label_2.Visible = 'off';
                app.Label_3.Visible = 'off';
                app.ClassLabel.Visible = 'off';
                app.ClassLabel_2.Visible = 'off';
                app.ClassLabel_3.Visible = 'off';

                imageFilePathTest = imdsTest_im.Files{index};
                imdsTest_im_ra = imread(imageFilePathTest);
                pixFilePathTest = pxdsTest.Files{index};
                pxdsTest_ra = imread(pixFilePathTest);
                imshow(labeloverlay(imdsTest_im_ra, pxdsTest_ra, 'Transparency', changingValue), 'Parent', app.UIAxes2_2)
             end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.Tooltip = {'Create and train new model. '};
            app.PushTool.ClickedCallback = createCallbackFcn(app, @PushToolClicked, true);
            app.PushTool.Icon = fullfile(pathToMLAPP, 'add-file_14591969.png');
            app.PushTool.Separator = 'on';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.NetworkTab = uitab(app.TabGroup);
            app.NetworkTab.Title = 'Network';
            app.TabGroup2 = uitabgroup(app.NetworkTab);
            app.TabGroup2.SelectionChangedFcn = createCallbackFcn(app, @TabGroup2SelectionChanged, true);
            app.ClassificationTab = uitab(app.TabGroup2);
            app.ClassificationTab.Title = 'Classification';
            app.SelectmodelButtonGroup = uibuttongroup(app.ClassificationTab);
            app.SelectmodelButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectmodelButtonGroupSelectionChanged, true);
            app.SelectmodelButtonGroup.Title = 'Select model';
            app.BuildfromscratchButton = uitogglebutton(app.SelectmodelButtonGroup);
            app.BuildfromscratchButton.Text = 'Build from scratch';
            app.BuildfromscratchButton.Value = true;
            app.AlexNetButton = uitogglebutton(app.SelectmodelButtonGroup);
            app.AlexNetButton.Text = 'AlexNet';
            app.Incepctionv3Button = uitogglebutton(app.SelectmodelButtonGroup);
            app.Incepctionv3Button.Text = 'Incepctionv3';
            app.MobileNetv2Button = uitogglebutton(app.SelectmodelButtonGroup);
            app.MobileNetv2Button.Text = 'MobileNetv2';
            app.ResNet50Button = uitogglebutton(app.SelectmodelButtonGroup);
            app.ResNet50Button.Text = 'ResNet-50';
            app.IncludeweightsCheckBox = uicheckbox(app.SelectmodelButtonGroup);
            app.IncludeweightsCheckBox.Text = 'Include weights';
            app.IncludeweightsCheckBox.Value = true;
            app.ImagesizeEditField_y = uieditfield(app.SelectmodelButtonGroup, 'numeric');
            app.ImagesizeEditField_y.Limits = [2 Inf];
            app.ImagesizeEditField_y.Value = 128;
            app.ImagesizeEditField_z = uieditfield(app.SelectmodelButtonGroup, 'numeric');
            app.ImagesizeEditField_z.Limits = [1 3];
            app.ImagesizeEditField_z.Value = 1;
            app.ImagesizeEditFieldLabel_5 = uilabel(app.SelectmodelButtonGroup);
            app.ImagesizeEditFieldLabel_5.Text = 'x';
            app.ImagesizeEditFieldLabel_6 = uilabel(app.SelectmodelButtonGroup);
            app.ImagesizeEditFieldLabel_6.Text = 'y';
            app.ImagesizeEditFieldLabel_7 = uilabel(app.SelectmodelButtonGroup);
            app.ImagesizeEditFieldLabel_7.Text = 'z';
            app.ImagesizeEditField_2Label = uilabel(app.SelectmodelButtonGroup);
            app.ImagesizeEditField_2Label.Text = 'Image size';
            app.ImagesizeEditField_x = uieditfield(app.SelectmodelButtonGroup, 'numeric');
            app.ImagesizeEditField_x.Limits = [2 Inf];
            app.ImagesizeEditField_x.Value = 128;
            app.NumberofclassesEditField_2Label = uilabel(app.SelectmodelButtonGroup);
            app.NumberofclassesEditField_2Label.Text = 'Number of classes';
            app.NumberofclassesEditField_2 = uieditfield(app.SelectmodelButtonGroup, 'numeric');
            app.NumberofclassesEditField_2.Limits = [1 Inf];
            app.NumberofclassesEditField_2.RoundFractionalValues = 'on';
            app.NumberofclassesEditField_2.Value = 1;
            app.SegmentationTab = uitab(app.TabGroup2);
            app.SegmentationTab.Title = 'Segmentation';
            app.SelectmodelButtonGroup_2 = uibuttongroup(app.SegmentationTab);
            app.SelectmodelButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @SelectmodelButtonGroup_2SelectionChanged, true);
            app.SelectmodelButtonGroup_2.Title = 'Select model';
            app.BuildfromscratchButton_2 = uitogglebutton(app.SelectmodelButtonGroup_2);
            app.BuildfromscratchButton_2.Text = 'Build from scratch';
            app.BuildfromscratchButton_2.Value = true;
            app.DeepLabv3Button = uitogglebutton(app.SelectmodelButtonGroup_2);
            app.DeepLabv3Button.Text = 'DeepLabv3+';
            app.UNetButton = uitogglebutton(app.SelectmodelButtonGroup_2);
            app.UNetButton.Text = 'UNet';
            app.IncludeweightsCheckBox_2 = uicheckbox(app.SelectmodelButtonGroup_2);
            app.IncludeweightsCheckBox_2.Text = 'Include weights';
            app.IncludeweightsCheckBox_2.Value = true;
            app.NumberofclassesEditFieldLabel = uilabel(app.SelectmodelButtonGroup_2);
            app.NumberofclassesEditFieldLabel.Text = 'Number of classes';
            app.NumberofclassesEditField = uieditfield(app.SelectmodelButtonGroup_2, 'numeric');
            app.NumberofclassesEditField.Limits = [2 Inf];
            app.NumberofclassesEditField.RoundFractionalValues = 'on';
            app.NumberofclassesEditField.Value = 2;
            app.BackboneDropDownLabel = uilabel(app.SelectmodelButtonGroup_2);
            app.BackboneDropDownLabel.Text = 'Backbone';
            app.BackboneDropDown = uidropdown(app.SelectmodelButtonGroup_2);
            app.BackboneDropDown.Items = {'ResNet50'};
            app.BackboneDropDown.Enable = 'off';
            app.BackboneDropDown.Value = 'ResNet50';
            app.ImagesizeEditFieldLabel = uilabel(app.SelectmodelButtonGroup_2);
            app.ImagesizeEditFieldLabel.Text = 'Image size';
            app.ImagesizeEditField_x_s = uieditfield(app.SelectmodelButtonGroup_2, 'numeric');
            app.ImagesizeEditField_x_s.Limits = [2 Inf];
            app.ImagesizeEditField_x_s.RoundFractionalValues = 'on';
            app.ImagesizeEditField_x_s.Value = 224;
            app.ImagesizeEditField_y_s = uieditfield(app.SelectmodelButtonGroup_2, 'numeric');
            app.ImagesizeEditField_y_s.Limits = [2 Inf];
            app.ImagesizeEditField_y_s.RoundFractionalValues = 'on';
            app.ImagesizeEditField_y_s.Value = 224;
            app.ImagesizeEditField_z_s = uieditfield(app.SelectmodelButtonGroup_2, 'numeric');
            app.ImagesizeEditField_z_s.Limits = [1 3];
            app.ImagesizeEditField_z_s.RoundFractionalValues = 'on';
            app.ImagesizeEditField_z_s.Value = 3;
            app.ImagesizeEditFieldLabel_2 = uilabel(app.SelectmodelButtonGroup_2);
            app.ImagesizeEditFieldLabel_2.Text = 'x';
            app.ImagesizeEditFieldLabel_3 = uilabel(app.SelectmodelButtonGroup_2);
            app.ImagesizeEditFieldLabel_3.Text = 'y';
            app.ImagesizeEditFieldLabel_4 = uilabel(app.SelectmodelButtonGroup_2);
            app.ImagesizeEditFieldLabel_4.Text = 'z';
            app.ImagesizeEditFieldLabel_8 = uilabel(app.SelectmodelButtonGroup_2);
            app.ImagesizeEditFieldLabel_8.Text = 'if DeepLabv3+';
            app.DetectionTab = uitab(app.TabGroup2);
            app.DetectionTab.Title = 'Detection';
            app.SelectmodelButtonGroup_3 = uibuttongroup(app.DetectionTab);
            app.SelectmodelButtonGroup_3.Title = 'Select model';
            app.BuildfromscratchButton_3 = uitogglebutton(app.SelectmodelButtonGroup_3);
            app.BuildfromscratchButton_3.Text = 'Build from scratch';
            app.BuildfromscratchButton_3.Value = true;
            app.YOLOv4Button = uitogglebutton(app.SelectmodelButtonGroup_3);
            app.YOLOv4Button.Text = 'YOLOv4';
            app.IncludeweightsCheckBox_3 = uicheckbox(app.SelectmodelButtonGroup_3);
            app.IncludeweightsCheckBox_3.Text = 'Include weights';
            app.IncludeweightsCheckBox_3.Value = true;
            app.ApplyButton = uibutton(app.NetworkTab, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.LoaddataButton = uibutton(app.DataTab, 'push');
            app.LoaddataButton.ButtonPushedFcn = createCallbackFcn(app, @LoaddataButtonPushed, true);
            app.LoaddataButton.Tooltip = {'Indicate folder with data.'};
            app.LoaddataButton.Text = 'Load data';
            app.TraindatasetEditFieldLabel = uilabel(app.DataTab);
            app.TraindatasetEditFieldLabel.Text = 'Train dataset ';
            app.TraindatasetEditField = uieditfield(app.DataTab, 'numeric');
            app.TraindatasetEditField.Value = 0.8;
            app.ValidationdatasetEditFieldLabel = uilabel(app.DataTab);
            app.ValidationdatasetEditFieldLabel.Text = 'Validation dataset ';
            app.ValidationdatasetEditField = uieditfield(app.DataTab, 'numeric');
            app.ValidationdatasetEditField.Value = 0.1;
            app.IncludevalidationCheckBox = uicheckbox(app.DataTab);
            app.IncludevalidationCheckBox.Text = 'Include validation ';
            app.IncludevalidationCheckBox.Value = true;
            app.TestdatasetLabel = uilabel(app.DataTab);
            app.TestdatasetLabel.Text = 'Test dataset ';
            app.TestdatasetEditField = uieditfield(app.DataTab, 'numeric');
            app.TestdatasetEditField.Value = 0.1;
            app.TabGroup3 = uitabgroup(app.DataTab);
            app.TrainTab_2 = uitab(app.TabGroup3);
            app.TrainTab_2.Title = 'Train';
            app.UIAxes = uiaxes(app.TrainTab_2);
            title(app.UIAxes, 'Labels distribution')
            xlabel(app.UIAxes, 'Class')
            ylabel(app.UIAxes, 'Count')
            app.UIAxes2 = uiaxes(app.TrainTab_2);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.Slider = uislider(app.TrainTab_2);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Label = uilabel(app.TrainTab_2);
            app.Label.Text = '-----';
            app.ClassLabel = uilabel(app.TrainTab_2);
            app.ClassLabel.Text = 'Class:';
            app.TransparencySliderLabel = uilabel(app.TrainTab_2);
            app.TransparencySliderLabel.Text = 'Transparency';
            app.TransparencySlider = uislider(app.TrainTab_2);
            app.TransparencySlider.Limits = [0 1];
            app.TransparencySlider.ValueChangedFcn = createCallbackFcn(app, @TransparencySliderValueChanged, true);
            app.TransparencySlider.ValueChangingFcn = createCallbackFcn(app, @TransparencySliderValueChanging, true);
            app.TransparencySlider.Value = 0.5;
            app.ValidationTab = uitab(app.TabGroup3);
            app.ValidationTab.Title = 'Validation';
            app.UIAxes_2 = uiaxes(app.ValidationTab);
            title(app.UIAxes_2, 'Labels distribution')
            xlabel(app.UIAxes_2, 'Class')
            ylabel(app.UIAxes_2, 'Count')
            app.UIAxes2_2 = uiaxes(app.ValidationTab);
            app.UIAxes2_2.XTick = [];
            app.UIAxes2_2.YTick = [];
            app.Slider_2 = uislider(app.ValidationTab);
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Slider_2.ValueChangingFcn = createCallbackFcn(app, @Slider_2ValueChanging, true);
            app.Label_2 = uilabel(app.ValidationTab);
            app.Label_2.Text = '-----';
            app.ClassLabel_2 = uilabel(app.ValidationTab);
            app.ClassLabel_2.Text = 'Class:';
            app.TransparencySlider_2Label = uilabel(app.ValidationTab);
            app.TransparencySlider_2Label.Text = 'Transparency';
            app.TransparencySlider_2 = uislider(app.ValidationTab);
            app.TransparencySlider_2.Limits = [0 1];
            app.TransparencySlider_2.ValueChangedFcn = createCallbackFcn(app, @TransparencySlider_2ValueChanged, true);
            app.TransparencySlider_2.ValueChangingFcn = createCallbackFcn(app, @TransparencySlider_2ValueChanging, true);
            app.TransparencySlider_2.Value = 0.5;
            app.TestTab_2 = uitab(app.TabGroup3);
            app.TestTab_2.Title = 'Test';
            app.UIAxes_3 = uiaxes(app.TestTab_2);
            title(app.UIAxes_3, 'Labels distribution')
            xlabel(app.UIAxes_3, 'Class')
            ylabel(app.UIAxes_3, 'Count')
            app.UIAxes2_3 = uiaxes(app.TestTab_2);
            app.UIAxes2_3.XTick = [];
            app.UIAxes2_3.YTick = [];
            app.Slider_3 = uislider(app.TestTab_2);
            app.Slider_3.ValueChangedFcn = createCallbackFcn(app, @Slider_3ValueChanged, true);
            app.Slider_3.ValueChangingFcn = createCallbackFcn(app, @Slider_3ValueChanging, true);
            app.Label_3 = uilabel(app.TestTab_2);
            app.Label_3.Text = '-----';
            app.ClassLabel_3 = uilabel(app.TestTab_2);
            app.ClassLabel_3.Text = 'Class:';
            app.TransparencySlider_3Label = uilabel(app.TestTab_2);
            app.TransparencySlider_3Label.Text = 'Transparency';
            app.TransparencySlider_3 = uislider(app.TestTab_2);
            app.TransparencySlider_3.Limits = [0 1];
            app.TransparencySlider_3.ValueChangedFcn = createCallbackFcn(app, @TransparencySlider_3ValueChanged, true);
            app.TransparencySlider_3.ValueChangingFcn = createCallbackFcn(app, @TransparencySlider_3ValueChanging, true);
            app.TransparencySlider_3.Value = 0.5;
            app.DatasetsstatisticsLabel = uilabel(app.DataTab);
            app.DatasetsstatisticsLabel.Text = 'Datasets statistics ';
            app.LabelssourcefoldernamesCheckBox = uicheckbox(app.DataTab);
            app.LabelssourcefoldernamesCheckBox.ValueChangedFcn = createCallbackFcn(app, @LabelssourcefoldernamesCheckBoxValueChanged, true);
            app.LabelssourcefoldernamesCheckBox.Text = 'Labels source: folder names';
            app.LabelssourcefoldernamesCheckBox.Value = true;
            app.LabelssourcetableCheckBox = uicheckbox(app.DataTab);
            app.LabelssourcetableCheckBox.ValueChangedFcn = createCallbackFcn(app, @LabelssourcetableCheckBoxValueChanged, true);
            app.LabelssourcetableCheckBox.Text = 'Labels source: table';
            app.ImporttableButton = uibutton(app.DataTab, 'push');
            app.ImporttableButton.ButtonPushedFcn = createCallbackFcn(app, @ImporttableButtonPushed, true);
            app.ImporttableButton.Enable = 'off';
            app.ImporttableButton.Text = 'Import table';
            app.TrainValidationTestdatarandompartitioningLabel = uilabel(app.DataTab);
            app.TrainValidationTestdatarandompartitioningLabel.Text = 'Train - Validation - Test data random partitioning';
            app.ApplyButton_2 = uibutton(app.DataTab, 'push');
            app.ApplyButton_2.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_2Pushed, true);
            app.ApplyButton_2.Text = 'Apply';
            app.ClassnamesTextAreaLabel = uilabel(app.DataTab);
            app.ClassnamesTextAreaLabel.Text = 'Class names';
            app.ClassnamesTextArea = uitextarea(app.DataTab);
            app.ClassnamesTextArea.Value = {'background;object'};
            app.Image = uiimage(app.DataTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, '1.png');
            app.DataaugmentationTab = uitab(app.TabGroup);
            app.DataaugmentationTab.Title = 'Data augmentation';
            app.DataaugmentationTab.ButtonDownFcn = createCallbackFcn(app, @DataaugmentationTabButtonDown, true);
            app.PanelClassification = uipanel(app.DataaugmentationTab);
            app.UIAxes4 = uiaxes(app.PanelClassification);
            app.UIAxes4_2 = uiaxes(app.PanelClassification);
            app.UIAxes4_7 = uiaxes(app.PanelClassification);
            app.UIAxes4_7.XTick = [];
            app.UIAxes4_7.YTick = [];
            app.UIAxes4_7.ZTick = [];
            app.UIAxes4_6 = uiaxes(app.PanelClassification);
            app.UIAxes4_6.XTick = [];
            app.UIAxes4_6.XTickLabel = '';
            app.UIAxes4_6.YTick = [];
            app.UIAxes4_6.YTickLabel = '';
            app.UIAxes4_6.ZTick = [];
            app.UIAxes4_5 = uiaxes(app.PanelClassification);
            app.UIAxes4_4 = uiaxes(app.PanelClassification);
            app.UIAxes4_3 = uiaxes(app.PanelClassification);
            app.UITable = uitable(app.DataaugmentationTab);
            app.UITable.ColumnName = {'Operation'; 'Value'; 'Description'; 'Apply'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [false false false];
            app.UITable.ColumnEditable = [false true false true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.PreviewaugmentationresultsLabel = uilabel(app.DataaugmentationTab);
            app.PreviewaugmentationresultsLabel.Text = 'Preview augmentation results';
            app.PanelSegmentation = uipanel(app.DataaugmentationTab);
            app.UIAxes4_10 = uiaxes(app.PanelSegmentation);
            app.UIAxes4_11 = uiaxes(app.PanelSegmentation);
            app.UIAxes4_12 = uiaxes(app.PanelSegmentation);
            app.UIAxes4_13 = uiaxes(app.PanelSegmentation);
            app.UIAxes4_14 = uiaxes(app.PanelSegmentation);
            app.UIAxes4_15 = uiaxes(app.PanelSegmentation);
            app.TrainTab = uitab(app.TabGroup);
            app.TrainTab.Title = 'Train';
            app.UIAxesAccuracy = uiaxes(app.TrainTab);
            title(app.UIAxesAccuracy, 'Title')
            xlabel(app.UIAxesAccuracy, 'X')
            ylabel(app.UIAxesAccuracy, 'Y')
            zlabel(app.UIAxesAccuracy, 'Z')
            app.UIAxesLoss = uiaxes(app.TrainTab);
            title(app.UIAxesLoss, 'Title')
            xlabel(app.UIAxesLoss, 'X')
            ylabel(app.UIAxesLoss, 'Y')
            zlabel(app.UIAxesLoss, 'Z')
            app.HyperparametersPanel = uipanel(app.TrainTab);
            app.HyperparametersPanel.Title = 'Hyperparameters';
            app.OptimizerDropDownLabel = uilabel(app.HyperparametersPanel);
            app.OptimizerDropDownLabel.Text = 'Optimizer';
            app.OptimizerDropDown = uidropdown(app.HyperparametersPanel);
            app.OptimizerDropDown.Items = {'sgdm', 'rmsprop', 'adam ', 'lbfgs'};
            app.OptimizerDropDown.ValueChangedFcn = createCallbackFcn(app, @OptimizerDropDownValueChanged, true);
            app.OptimizerDropDown.Value = 'sgdm';
            app.UITable2 = uitable(app.HyperparametersPanel);
            app.UITable2.ColumnName = {'Hyperparameter'; 'Value'};
            app.UITable2.RowName = {};
            app.UITable2.ColumnEditable = [false true];
            app.TrainButton = uibutton(app.HyperparametersPanel, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed, true);
            app.TrainButton.Text = 'Train';
            app.LossfunctionDropDownLabel = uilabel(app.HyperparametersPanel);
            app.LossfunctionDropDownLabel.Text = 'Loss function';
            app.LossfunctionDropDown = uidropdown(app.HyperparametersPanel);
            app.LossfunctionDropDown.Items = {'crossentropy', 'index-crossentropy', 'binary-crossentropy', 'mean-absolute-error', 'mean-squared-error', 'huber'};
            app.LossfunctionDropDown.Value = 'crossentropy';
            app.ModeltotrainDropDownLabel = uilabel(app.HyperparametersPanel);
            app.ModeltotrainDropDownLabel.Text = 'Model to train';
            app.ModeltotrainDropDown = uidropdown(app.HyperparametersPanel);
            app.ModeltotrainDropDown.Items = {};
            app.ModeltotrainDropDown.Value = {};
            app.TrainingwaitingtostartLabel = uilabel(app.TrainTab);
            app.TrainingwaitingtostartLabel.Text = 'Training: waiting to start';
            app.TimeelapsedLabel = uilabel(app.TrainTab);
            app.TimeelapsedLabel.Text = 'Time elapsed: ----';
            app.TestTab = uitab(app.TabGroup);
            app.TestTab.Title = 'Test';
            app.UIAxesConfMat = uiaxes(app.TestTab);
            title(app.UIAxesConfMat, 'Confusion matrix')
            app.UIAxesConfMat.XTick = [];
            app.UIAxesConfMat.YTick = [];
            app.UIAxesConfMat.ZTick = [];
            app.UIAxes4_8 = uiaxes(app.TestTab);
            app.UIAxes4_9 = uiaxes(app.TestTab);
            app.TestmetricsPanel = uipanel(app.TestTab);
            app.TestmetricsPanel.Title = 'Test metrics';
            app.AccuracyLabel = uilabel(app.TestmetricsPanel);
            app.AccuracyLabel.Text = 'Accuracy: ';
            app.PrecisionLabel = uilabel(app.TestmetricsPanel);
            app.PrecisionLabel.Text = 'Precision: ';
            app.RecallLabel = uilabel(app.TestmetricsPanel);
            app.RecallLabel.Text = 'Recall: ';
            app.F1scoreLabel = uilabel(app.TestmetricsPanel);
            app.F1scoreLabel.Text = 'F1 score: ';
            app.AreaunderROCcurveLabel = uilabel(app.TestmetricsPanel);
            app.AreaunderROCcurveLabel.Text = 'Area under ROC curve:';
            app.SelectsavedmodelDropDownLabel = uilabel(app.TestTab);
            app.SelectsavedmodelDropDownLabel.Text = 'Select saved model';
            app.SelectsavedmodelDropDown = uidropdown(app.TestTab);
            app.SelectsavedmodelDropDown.Items = {};
            app.SelectsavedmodelDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectsavedmodelDropDownValueChanged, true);
            app.SelectsavedmodelDropDown.Value = {};
            app.GroundtruthclassLabel = uilabel(app.TestTab);
            app.GroundtruthclassLabel.Text = 'Ground truth class: ';
            app.PredictedclassLabel = uilabel(app.TestTab);
            app.PredictedclassLabel.Text = 'Predicted class: ';
            app.GradCAMButton = uibutton(app.TestTab, 'push');
            app.GradCAMButton.ButtonPushedFcn = createCallbackFcn(app, @GradCAMButtonPushed, true);
            app.GradCAMButton.Text = 'GradCAM';
            app.Slider_4 = uislider(app.TestTab);
            app.Slider_4.ValueChangedFcn = createCallbackFcn(app, @Slider_4ValueChanged, true);
            app.Slider_4.ValueChangingFcn = createCallbackFcn(app, @Slider_4ValueChanging, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end